classdef CurvadeAfluencia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UnidadButtonGroup           matlab.ui.container.ButtonGroup
        MexicoButton                matlab.ui.control.ToggleButton
        InglesButton                matlab.ui.control.ToggleButton
        UITable                     matlab.ui.control.Table
        Pb                          matlab.ui.control.NumericEditField
        PresindeburbujaLabel        matlab.ui.control.Label
        Subtitulo                   matlab.ui.control.Label
        Pwf                         matlab.ui.control.NumericEditField
        Pws                         matlab.ui.control.NumericEditField
        Gasto                       matlab.ui.control.NumericEditField
        EF                          matlab.ui.control.NumericEditField
        CalcularIPRButton           matlab.ui.control.Button
        EficienciadeflujoLabel      matlab.ui.control.Label
        GastodeaceiteLabel          matlab.ui.control.Label
        PresindefondoestticaLabel   matlab.ui.control.Label
        PresindefondofluyenteLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Sistema de unidades actualmente seleccionado
        SistemaActual = 'ingles';
        
        % Indica si ya existe un cálculo realizado
        CalculoRealizado = false;
        
        % Guarda los resultados del último cálculo en sistema inglés Presión: psi y Gasto: STB/d
        ResultadosIPR = struct();
    end
    
    methods (Access = private)
        
        % Valida que los datos de entrada sean consistentes.
        function esValido = validarEntradas(app, Pb, Pwf, Pws, Qo, EF)
            % Si algún dato no es válido, muestra una alerta y devuelve false.
        
            esValido = false;
        
            % Validar presión estática
            if Pws <= 0
                uialert(app.UIFigure, ...
                    'La presión estática Pws debe ser mayor que cero.', ...
                    'Error de entrada');
                return;
            end
        
            % Validar presión de fondo fluyente
            if Pwf < 0
                uialert(app.UIFigure, ...
                    'La presión de fondo fluyente Pwf no puede ser negativa.', ...
                    'Error de entrada');
                return;
            end
        
            % Validar que Pwf sea menor que Pws
            if Pwf >= Pws
                uialert(app.UIFigure, ...
                    'La presión Pwf debe ser menor que la presión Pws.', ...
                    'Error de entrada');
                return;
            end
        
            % Validar gasto
            if Qo <= 0
                uialert(app.UIFigure, ...
                    'El gasto debe ser mayor que cero.', ...
                    'Error de entrada');
                return;
            end
        
            % Validar eficiencia de flujo
            if EF <= 0
                uialert(app.UIFigure, ...
                    'La eficiencia de flujo EF debe ser mayor que cero.', ...
                    'Error de entrada');
                return;
            end
        
            % Validar presión de burbuja
            if Pb <= 0
                uialert(app.UIFigure, ...
                    'La presión de burbuja Pb debe ser mayor que cero.', ...
                    'Error de entrada');
                return;
            end
        
            % Validación recomendada para Vogel generalizado
            if Pb > Pws
                uialert(app.UIFigure, ...
                    'La presión de burbuja Pb no debería ser mayor que Pws para este modelo.', ...
                    'Error de entrada');
                return;
            end
        
            % Advertencia cuando Pb es igual a Pws
            if Pb == Pws
                uialert(app.UIFigure, ...
                    'Pb es igual a Pws; el gasto a Pb será cero en Vogel generalizado.', ...
                    'Advertencia');
            end
        
            esValido = true;
        
        end

        % Método de Moore.
        function [J, qmax, gastos] = calcularMoore(~, Presiones, Pwf, Pws, Qo)
            J = Qo / (Pws - Pwf);
            qmax = J * Pws;
            gastos = J * (Pws - Presiones);
        end

        % Método de Vogel para EF = 1.
        function [qmax, gastos] = calcularVogel(~, Presiones, Pwf, Pws, Qo)
            relacionDato = Pwf / Pws;
            denominador = 1 - (0.2 * relacionDato) - (0.8 * relacionDato^2);
            qmax = Qo / denominador;
            relacionCurva = Presiones / Pws;
            gastos = qmax * (1 - (0.2 * relacionCurva) - (0.8 * relacionCurva.^2));
        end

        % Método de Standing para eficiencia de flujo diferente de 1.
        function [qmax, gastos] = calcularStanding(~, PwfPrima, Pwf, Pws, Qo)
            relacionDato = Pwf / Pws;
            denominador = 1 - (0.2 * relacionDato) - (0.8 * relacionDato^2);    
            qmax = Qo / denominador;       
            relacionCurva = PwfPrima / Pws;
            gastos = qmax * (1 - (0.2 * relacionCurva) - (0.8 * relacionCurva.^2));    
        end

        % Método de Harrison. Aplica cuando Pwf' presenta valores negativos.
        function [qmax, gastos] = calcularHarrison(~, PwfPrima, Pwf, Pws, Qo, EF)
            PwfPrimaDato = Pws - ((Pws - Pwf) * EF);
            denominador = 1.2 - (0.2 * exp(1.792 * (PwfPrimaDato / Pws)));
            qmax = Qo / denominador;
            gastos = qmax * (1.2 - (0.2 * exp(1.792 * (PwfPrima / Pws))));
        end
            
        % Método de Vogel generalizado. Para presiones menores o iguales a Pb se usa la ecuación generalizada con el gasto calculado a presión de burbuja.
        function [GastoBurbuja, gastos, Presiones_Pb] = calcularVogelGeneralizado(~, Pb, Pws, J)
            % Vector de presiones desde cero hasta Pb
            Presiones_Pb = linspace(0, Pb, 100);
            % Gasto a presión de burbuja
            GastoBurbuja = J * (Pws - Pb);
            % Relación de presión para el tramo bajo Pb
            relacionPb = Presiones_Pb / Pb;
            % Cálculo del gasto generalizado
            gastos = GastoBurbuja + (((J * Pb) / 1.8) .* (1 - (0.2 * relacionPb) - (0.8 * relacionPb.^2)));
        end

        % Detecta qué botón del grupo de unidades está seleccionado.
        function sistema = obtenerSistemaUnidades(app)
            % 'ingles' -> psi y STB/d
            % 'mexico' -> kg/cm² y m³/d
            if app.UnidadButtonGroup.SelectedObject == app.MexicoButton
                sistema = 'mexico';
            else
                sistema = 'ingles';
            end
        end

        % Define los textos de unidades que se mostrarán en la interfaz.
        function [unidadPresion, unidadGasto] = obtenerTextosUnidades(~, sistema)

            switch sistema
                case 'mexico'
                    unidadPresion = 'kg/cm²';
                    unidadGasto   = 'm³/d';
                otherwise
                    unidadPresion = 'psi';
                    unidadGasto   = 'STB/d';
            end
        
        end

        % Convierte los datos capturados por el usuario a sistema inglés.
        function [Pb_psi, Pwf_psi, Pws_psi, Qo_stbd] = convertirEntradaAIngles(~, Pb, Pwf, Pws, Qo, sistema)
            % Sistema inglés:
            %   Presión = psi
            %   Gasto   = STB/d
            %
            % Sistema Métrico:
            %   Presión = kg/cm²
            %   Gasto   = m³/d
            %
            % Factores usados:
            %   1 kg/cm² = 14.2233 psi
            %   1 m³/d   = 6.28981 STB/d
            switch sistema
                case 'mexico'
        
                    % Conversión de presión de kg/cm² a psi
                    Pb_psi  = Pb  * 14.2233;
                    Pwf_psi = Pwf * 14.2233;
                    Pws_psi = Pws * 14.2233;
        
                    % Conversión de gasto de m³/d a STB/d
                    Qo_stbd = Qo * 6.28981;
        
                otherwise
                    % Si está en sistema inglés, no se convierte
                    Pb_psi  = Pb;
                    Pwf_psi = Pwf;
                    Pws_psi = Pws;
                    Qo_stbd = Qo;
            end
        end

        % Convierte presión desde psi al sistema seleccionado.
        function P_display = convertirPresionDesdeIngles(~, P_psi, sistema)
            % Si sistema = inglés:
            %   psi -> psi
            %
            % Si sistema = Métrico:
            %   psi -> kg/cm²
        
            switch sistema
                case 'mexico'
                    P_display = P_psi / 14.2233;
        
                otherwise
                    P_display = P_psi;
            end
        
        end

        % Convierte gasto desde STB/d al sistema seleccionado.
        function q_display = convertirGastoDesdeIngles(~, q_stbd, sistema)
            % Si sistema = inglés:
            %   STB/d -> STB/d
            %
            % Si sistema = México:
            %   STB/d -> m³/d
        
            switch sistema
                case 'mexico'
                    q_display = q_stbd / 6.28981;
        
                otherwise
                    q_display = q_stbd;
            end
 
        end

        % Actualiza app.UITable con los resultados de Qo max.
        function actualizarTablaResultados(app, resultadosTabla, unidadGasto)
            % La tabla solo muestra:
            %   Método
            %   Qo max en el sistema de unidades seleccionado
        
            % Si no hay resultados, limpiar la tabla
            if isempty(resultadosTabla)
                app.UITable.Data = {};
                return;
            end
        
            % Redondear valores numéricos a dos decimales
            for i = 1:size(resultadosTabla, 1)
                resultadosTabla{i, 2} = round(resultadosTabla{i, 2}, 2);
            end
        
            % Encabezados de la tabla
            app.UITable.ColumnName = {'Método', ['Qo max (' unidadGasto ')']};
        
            % Datos de la tabla
            app.UITable.Data = resultadosTabla;
        
        end

        % Guarda todos los resultados calculados en sistema inglés.
        function guardarResultadosIPR(app, Presiones, Qo, Pwf, Pws, ...
            Moore_Gastos, Moore_GastoMax, ...
            GastosVogel, Vogel_GastoMax, ...
            GastosStanding, Standing_GastoMax, ...
            Gasto_Harrison, Harrison_GastoMax, ...
            VogelGeneralizado_Gasto, Presiones_Pb, GastoBurbuja, ...
            EF)

            % Sistema interno:
            %   Presión = psi
            %   Gasto   = STB/d
            %
            % Estos datos se usan después para redibujar la gráfica y la tabla cuando el usuario cambie el sistema de unidades.
        
            % Datos generales
            app.ResultadosIPR.Presiones = Presiones;
            app.ResultadosIPR.Qo = Qo;
            app.ResultadosIPR.Pwf = Pwf;
            app.ResultadosIPR.Pws = Pws;
            app.ResultadosIPR.EF = EF;
        
            % Moore
            app.ResultadosIPR.Moore_Gastos = Moore_Gastos;
            app.ResultadosIPR.Moore_GastoMax = Moore_GastoMax;
        
            % Vogel
            app.ResultadosIPR.GastosVogel = GastosVogel;
            app.ResultadosIPR.Vogel_GastoMax = Vogel_GastoMax;
        
            % Standing
            app.ResultadosIPR.GastosStanding = GastosStanding;
            app.ResultadosIPR.Standing_GastoMax = Standing_GastoMax;
        
            % Harrison
            app.ResultadosIPR.Gasto_Harrison = Gasto_Harrison;
            app.ResultadosIPR.Harrison_GastoMax = Harrison_GastoMax;
        
            % Vogel generalizado
            app.ResultadosIPR.VogelGeneralizado_Gasto = VogelGeneralizado_Gasto;
            app.ResultadosIPR.Presiones_Pb = Presiones_Pb;
            app.ResultadosIPR.GastoBurbuja = GastoBurbuja;
        
            % Activar bandera de cálculo realizado
            app.CalculoRealizado = true;
        
        end

        % Actualiza etiquetas, encabezados y ejes de acuerdo con el sistema de unidades seleccionado.
        function actualizarTextosUnidades(app)
        
            sistema = obtenerSistemaUnidades(app);
        
            switch sistema
        
                case 'mexico'
        
                    app.UnidadButtonGroup.Title = 'Sistema de unidades';
                    app.PresindeburbujaLabel.Text       = 'Presión de burbuja:';
                    app.PresindefondofluyenteLabel.Text = 'Presión de fondo fluyente:';
                    app.PresindefondoestticaLabel.Text  = 'Presión de fondo estática:';
                    app.GastodeaceiteLabel.Text         = 'Gasto de aceite:';
        
                    xlabel(app.UIAxes, 'Gasto, q (m³/d)');
                    ylabel(app.UIAxes, 'Presión, Pwf (kg/cm²)');
        
                    app.UITable.ColumnName = {'Método'; 'Qo max (m³/d)'};
        
                otherwise
        
                    app.UnidadButtonGroup.Title = 'Sistema de unidades';
                    app.PresindeburbujaLabel.Text       = 'Presión de burbuja:';
                    app.PresindefondofluyenteLabel.Text = 'Presión de fondo fluyente:';
                    app.PresindefondoestticaLabel.Text  = 'Presión de fondo estática:';
                    app.GastodeaceiteLabel.Text         = 'Gasto de aceite:';
        
                    xlabel(app.UIAxes, 'Gasto, q (STB/d)');
                    ylabel(app.UIAxes, 'Presión, Pwf (psi)');
        
                    app.UITable.ColumnName = {'Método'; 'Qo max (STB/d)'};
            end
        
        end

        % Redibuja la gráfica y actualiza la tabla usando los resultado sguardados del último cálculo.
        function redibujarResultadosIPR(app)
            % No recalcula los modelos. Solo convierte unidades y actualiza la visualización.
        
            % Si todavía no se ha calculado nada, no hay nada que redibujar
            if ~app.CalculoRealizado
                return;
            end
        
            % Obtener sistema actualmente seleccionado
            sistema = obtenerSistemaUnidades(app);
        
            % Obtener textos de unidades
            [unidadPresion, unidadGasto] = obtenerTextosUnidades(app, sistema);
        
            % Limpiar gráfica
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'on');
        
            % Títulos y ejes
            title(app.UIAxes, 'Construcción de Curvas IPR');
            xlabel(app.UIAxes, ['Gasto, q (' unidadGasto ')']);
            ylabel(app.UIAxes, ['Presión, Pwf (' unidadPresion ')']);
        
            % Recuperar resultados guardados en sistema inglés
            R = app.ResultadosIPR;
        
            Presiones_plot = convertirPresionDesdeIngles(app, R.Presiones, sistema);
        
            resultadosTabla = {};
            gastosMaximos = [];
        
            % Moore
            Moore_Gastos_plot = convertirGastoDesdeIngles(app, R.Moore_Gastos, sistema);
            Moore_GastoMax_display = convertirGastoDesdeIngles(app, R.Moore_GastoMax, sistema);
        
            plot(app.UIAxes, Moore_Gastos_plot, Presiones_plot, ...
                'LineWidth', 1.6, ...
                'DisplayName', 'Moore');
        
            resultadosTabla(end + 1, :) = {'Moore', Moore_GastoMax_display};
            gastosMaximos(end + 1) = Moore_GastoMax_display;
        
            % Vogel
            GastosVogel_plot = convertirGastoDesdeIngles(app, R.GastosVogel, sistema);
            Vogel_GastoMax_display = convertirGastoDesdeIngles(app, R.Vogel_GastoMax, sistema);
        
            plot(app.UIAxes, GastosVogel_plot, Presiones_plot, ...
                'LineWidth', 1.6, ...
                'DisplayName', 'Vogel');
        
            resultadosTabla(end + 1, :) = {'Vogel', Vogel_GastoMax_display};
            gastosMaximos(end + 1) = Vogel_GastoMax_display;
        
            % Standing
            if ~isempty(R.GastosStanding)
        
                GastosStanding_plot = convertirGastoDesdeIngles(app, R.GastosStanding, sistema);
                Standing_GastoMax_display = convertirGastoDesdeIngles(app, R.Standing_GastoMax, sistema);
        
                plot(app.UIAxes, GastosStanding_plot, Presiones_plot, ...
                    'LineWidth', 1.6, ...
                    'DisplayName', sprintf('Standing, EF = %.2f', R.EF));
        
                resultadosTabla(end + 1, :) = {'Standing', Standing_GastoMax_display};
                gastosMaximos(end + 1) = Standing_GastoMax_display;
        
            end
        
            % Harrison
            if ~isempty(R.Gasto_Harrison)
        
                Gasto_Harrison_plot = convertirGastoDesdeIngles(app, R.Gasto_Harrison, sistema);
                Harrison_GastoMax_display = convertirGastoDesdeIngles(app, R.Harrison_GastoMax, sistema);
        
                plot(app.UIAxes, Gasto_Harrison_plot, Presiones_plot, ...
                    'LineWidth', 1.6, ...
                    'DisplayName', sprintf('Harrison, EF = %.2f', R.EF));
        
                resultadosTabla(end + 1, :) = {'Harrison', Harrison_GastoMax_display};
                gastosMaximos(end + 1) = Harrison_GastoMax_display;
        
            end
        
            % Vogel generalizado
            VogelGeneralizado_Gasto_plot = convertirGastoDesdeIngles(app, R.VogelGeneralizado_Gasto, sistema);
            Presiones_Pb_plot = convertirPresionDesdeIngles(app, R.Presiones_Pb, sistema);
            GastoBurbuja_display = convertirGastoDesdeIngles(app, R.GastoBurbuja, sistema);
        
            plot(app.UIAxes, VogelGeneralizado_Gasto_plot, Presiones_Pb_plot, ...
                'LineWidth', 1.6, ...
                'DisplayName', 'Vogel Generalizado');
        
            resultadosTabla(end + 1, :) = {'Vogel Gen. qPb', GastoBurbuja_display};
            gastosMaximos(end + 1) = max(VogelGeneralizado_Gasto_plot);
        
            % Punto de prueba
            Qo_plot = convertirGastoDesdeIngles(app, R.Qo, sistema);
            Pwf_plot = convertirPresionDesdeIngles(app, R.Pwf, sistema);
        
            plot(app.UIAxes, Qo_plot, Pwf_plot, 'o', ...
                'MarkerSize', 7, ...
                'LineWidth', 1.5, ...
                'DisplayName', 'Dato de prueba');
        
            % Actualizar tabla
            actualizarTablaResultados(app, resultadosTabla, unidadGasto);
        
            % Ajustar límites de ejes
            Pws_plot = convertirPresionDesdeIngles(app, R.Pws, sistema);
        
            app.UIAxes.YLim = [0 Pws_plot];
        
            gastosMaximos = gastosMaximos(isfinite(gastosMaximos));
        
            if ~isempty(gastosMaximos)
                app.UIAxes.XLim = [0 max(gastosMaximos) * 1.10];
            else
                app.UIAxes.XLim = [0 Qo_plot * 1.10];
            end
        
            legend(app.UIAxes, 'Location', 'best');
        
            hold(app.UIAxes, 'off');
        
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularIPRButton
        function CalcularIPRButtonPushed(app, event)
            % Esta función:
            % 1. Lee el sistema de unidades seleccionado.
            % 2. Convierte los datos de entrada al sistema inglés.
            % 3. Valida los datos.
            % 4. Calcula las curvas IPR.
            % 5. Grafica las curvas en el sistema seleccionado.
            % 6. Muestra Qo max en app.UITable.
        
            % 1. Identificar sistema de unidades seleccionado
            sistema = obtenerSistemaUnidades(app);
        
            % 2. Leer datos capturados por el usuario
            Pb_in  = app.Pb.Value;       % Presión de burbuja
            Pwf_in = app.Pwf.Value;      % Presión de fondo fluyente
            Pws_in = app.Pws.Value;      % Presión estática
            Qo_in  = app.Gasto.Value;    % Gasto
            EF     = app.EF.Value;       % Eficiencia de flujo
        
            % 3. Convertir datos de entrada al sistema inglés
            % Los cálculos internos se harán en psi y STB/d
            [Pb, Pwf, Pws, Qo] = convertirEntradaAIngles(app, Pb_in, Pwf_in, Pws_in, Qo_in, sistema);
        
            % 4. Validar datos ya convertidos a sistema inglés
            esValido = validarEntradas(app, Pb, Pwf, Pws, Qo, EF);
        
            if ~esValido
                return;
            end
        
            % 5. Preparar gráfica
            cla(app.UIAxes);                  % Limpia la gráfica
            hold(app.UIAxes, 'on');           % Permite varias curvas
            grid(app.UIAxes, 'on');           % Activa cuadrícula
            box(app.UIAxes, 'on');            % Activa borde de la gráfica
        
            % Obtener textos de unidades para mostrar en ejes y tabla
            [unidadPresion, unidadGasto] = obtenerTextosUnidades(app, sistema);
        
            title(app.UIAxes, 'Construcción de Curvas IPR');
            xlabel(app.UIAxes, ['Gasto, q (' unidadGasto ')']);
            ylabel(app.UIAxes, ['Presión, Pwf (' unidadPresion ')']);
        
            % Vector de presiones en sistema inglés
            Presiones = linspace(0, Pws, 100);
        
            % Arreglo auxiliar para ajustar el límite del eje X
            gastosMaximos = [];
        
            % Arreglo para guardar resultados de Qo max en la tabla
            resultadosTabla = {};
        
            % 6. MÉTODO MOORE
            [J, Moore_GastoMax, Moore_Gastos] = calcularMoore(app, Presiones, Pwf, Pws, Qo);
        
            % Convertir curva a sistema de visualización
            Moore_Gastos_plot = convertirGastoDesdeIngles(app, Moore_Gastos, sistema);
            Presiones_plot    = convertirPresionDesdeIngles(app, Presiones, sistema);
        
            % Graficar curva Moore
            plot(app.UIAxes, Moore_Gastos_plot, Presiones_plot, ...
                'LineWidth', 1.6, ...
                'DisplayName', 'Moore');
        
            % Guardar Qo max de Moore para la tabla
            Moore_GastoMax_display = convertirGastoDesdeIngles(app, Moore_GastoMax, sistema);
            resultadosTabla(end + 1, :) = {'Moore', Moore_GastoMax_display};
        
            gastosMaximos(end + 1) = Moore_GastoMax_display;
        
            % 7. MÉTODO VOGEL
            [Vogel_GastoMax, GastosVogel] = calcularVogel(app, Presiones, Pwf, Pws, Qo);

            % Variables opcionales para métodos que podrían no aplicar
            GastosStanding = [];
            Standing_GastoMax = [];
            
            Gasto_Harrison = [];
            Harrison_GastoMax = [];
        
            % Convertir curva a sistema de visualización
            GastosVogel_plot = convertirGastoDesdeIngles(app, GastosVogel, sistema);
        
            % Graficar curva Vogel
            plot(app.UIAxes, GastosVogel_plot, Presiones_plot, ...
                'LineWidth', 1.6, ...
                'DisplayName', 'Vogel');
        
            % Guardar Qo max de Vogel para la tabla
            Vogel_GastoMax_display = convertirGastoDesdeIngles(app, Vogel_GastoMax, sistema);
            resultadosTabla(end + 1, :) = {'Vogel', Vogel_GastoMax_display};
            gastosMaximos(end + 1) = Vogel_GastoMax_display;
        
            % 8. MÉTODOS PARA EF DIFERENTE DE 1
            % Standing y Harrison
            if EF ~= 1
        
                % Cálculo de Pwf' para cada punto de presión
                PwfPrima = Pws - ((Pws - Presiones) * EF);
        
                % MÉTODO STANDING
                [Standing_GastoMax, GastosStanding] = calcularStanding(app, PwfPrima, Pwf, Pws, Qo);
        
                % Convertir curva a sistema de visualización
                GastosStanding_plot = convertirGastoDesdeIngles(app, GastosStanding, sistema);
        
                % Graficar Standing
                plot(app.UIAxes, GastosStanding_plot, Presiones_plot, ...
                    'LineWidth', 1.6, ...
                    'DisplayName', sprintf('Standing, EF = %.2f', EF));
        
                % Guardar Qo max de Standing para la tabla
                Standing_GastoMax_display = convertirGastoDesdeIngles(app, Standing_GastoMax, sistema);
                resultadosTabla(end + 1, :) = {'Standing', Standing_GastoMax_display};
        
                gastosMaximos(end + 1) = Standing_GastoMax_display;
        
        
                % MÉTODO HARRISON
                if min(PwfPrima) < 0
        
                    [Harrison_GastoMax, Gasto_Harrison] = calcularHarrison(app, PwfPrima, Pwf, Pws, Qo, EF);
        
                    % Convertir curva a sistema de visualización
                    Gasto_Harrison_plot = convertirGastoDesdeIngles(app, Gasto_Harrison, sistema);
        
                    % Graficar Harrison
                    plot(app.UIAxes, Gasto_Harrison_plot, Presiones_plot, ...
                        'LineWidth', 1.6, ...
                        'DisplayName', sprintf('Harrison, EF = %.2f', EF));
        
                    % Guardar Qo max de Harrison para la tabla
                    Harrison_GastoMax_display = convertirGastoDesdeIngles(app, Harrison_GastoMax, sistema);
                    resultadosTabla(end + 1, :) = {'Harrison', Harrison_GastoMax_display};
        
                    gastosMaximos(end + 1) = Harrison_GastoMax_display;
        
                end
            end
        
            % 9. MÉTODO VOGEL GENERALIZADO
            [GastoBurbuja, VogelGeneralizado_Gasto, Presiones_Pb] = calcularVogelGeneralizado(app, Pb, Pws, J);
        
            % Convertir curva a sistema de visualización
            VogelGeneralizado_Gasto_plot = convertirGastoDesdeIngles(app, VogelGeneralizado_Gasto, sistema);
            Presiones_Pb_plot            = convertirPresionDesdeIngles(app, Presiones_Pb, sistema);
        
            % Graficar Vogel generalizado
            plot(app.UIAxes, VogelGeneralizado_Gasto_plot, Presiones_Pb_plot, ...
                'LineWidth', 1.6, ...
                'DisplayName', 'Vogel Generalizado');
        
            % Guardar gasto a Pb en la tabla
            GastoBurbuja_display = convertirGastoDesdeIngles(app, GastoBurbuja, sistema);
            resultadosTabla(end + 1, :) = {'Vogel Gen. qPb', GastoBurbuja_display};
        
            gastosMaximos(end + 1) = max(VogelGeneralizado_Gasto_plot);
        
        
            % 10. Graficar punto de prueba
            Qo_plot  = convertirGastoDesdeIngles(app, Qo, sistema);
            Pwf_plot = convertirPresionDesdeIngles(app, Pwf, sistema);
        
            plot(app.UIAxes, Qo_plot, Pwf_plot, 'o', ...
                'MarkerSize', 7, ...
                'LineWidth', 1.5, ...
                'DisplayName', 'Dato de prueba');

            % Guardar resultados en sistema inglés
            guardarResultadosIPR(app, Presiones, Qo, Pwf, Pws, ...
                Moore_Gastos, Moore_GastoMax, ...
                GastosVogel, Vogel_GastoMax, ...
                GastosStanding, Standing_GastoMax, ...
                Gasto_Harrison, Harrison_GastoMax, ...
                VogelGeneralizado_Gasto, Presiones_Pb, GastoBurbuja, ...
                EF);
            
            % Redibujar gráfica y tabla en el sistema seleccionado
            redibujarResultadosIPR(app);
        
            % 11. Actualizar tabla de resultados
            actualizarTablaResultados(app, resultadosTabla, unidadGasto);
        
            % 12. Ajustar límites de gráfica
            Pws_plot = convertirPresionDesdeIngles(app, Pws, sistema);
            app.UIAxes.YLim = [0 Pws_plot];
            gastosMaximos = gastosMaximos(isfinite(gastosMaximos));
        
            if ~isempty(gastosMaximos)
                app.UIAxes.XLim = [0 max(gastosMaximos) * 1.10];
            else
                app.UIAxes.XLim = [0 Qo_plot * 1.10];
            end
        
            legend(app.UIAxes, 'Location', 'best');
        
            hold(app.UIAxes, 'off');
        end

        % Selection changed function: UnidadButtonGroup
        function UnidadButtonGroupSelectionChanged(app, event)
            % Cambia el sistema de unidades visible.
            % Si ya existe un cálculo, no recalcula los modelos:
            % únicamente convierte los resultados guardados y redibuja.
        
            % Detectar sistema nuevo
            sistemaNuevo = obtenerSistemaUnidades(app);
        
            % Actualizar sistema actual
            app.SistemaActual = sistemaNuevo;
        
            % Actualizar textos de la interfaz
            actualizarTextosUnidades(app);
        
            % Si ya se calculó, redibujar resultados convertidos
            if app.CalculoRealizado
                redibujarResultadosIPR(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Construcción de Curvas IPR')
            xlabel(app.UIAxes, 'Gasto')
            ylabel(app.UIAxes, 'Presión')
            zlabel(app.UIAxes, 'Z')
            app.PresindefondofluyenteLabel = uilabel(app.UIFigure);
            app.PresindefondofluyenteLabel.Text = 'Presión de fondo fluyente:';
            app.PresindefondoestticaLabel = uilabel(app.UIFigure);
            app.PresindefondoestticaLabel.Text = 'Presión de fondo estática:';
            app.GastodeaceiteLabel = uilabel(app.UIFigure);
            app.GastodeaceiteLabel.Text = 'Gasto de aceite:';
            app.EficienciadeflujoLabel = uilabel(app.UIFigure);
            app.EficienciadeflujoLabel.Text = 'Eficiencia de flujo:';
            app.CalcularIPRButton = uibutton(app.UIFigure, 'push');
            app.CalcularIPRButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularIPRButtonPushed, true);
            app.CalcularIPRButton.Text = 'Calcular IPR';
            app.EF = uieditfield(app.UIFigure, 'numeric');
            app.EF.Limits = [0 Inf];
            app.EF.ValueDisplayFormat = '%.2f';
            app.EF.Placeholder = 'EF';
            app.EF.Value = 2;
            app.Gasto = uieditfield(app.UIFigure, 'numeric');
            app.Gasto.Limits = [0 Inf];
            app.Gasto.ValueDisplayFormat = '%.2f';
            app.Gasto.Placeholder = 'Qo';
            app.Gasto.Value = 200;
            app.Pws = uieditfield(app.UIFigure, 'numeric');
            app.Pws.Limits = [0 Inf];
            app.Pws.ValueDisplayFormat = '%.2f';
            app.Pws.Placeholder = 'Pws';
            app.Pws.Value = 2000;
            app.Pwf = uieditfield(app.UIFigure, 'numeric');
            app.Pwf.Limits = [0 Inf];
            app.Pwf.ValueDisplayFormat = '%.2f';
            app.Pwf.Placeholder = 'Pwf';
            app.Pwf.Value = 500;
            app.Subtitulo = uilabel(app.UIFigure);
            app.Subtitulo.Text = 'Datos de la prueba';
            app.PresindeburbujaLabel = uilabel(app.UIFigure);
            app.PresindeburbujaLabel.Text = 'Presión de burbuja:';
            app.Pb = uieditfield(app.UIFigure, 'numeric');
            app.Pb.Limits = [0 Inf];
            app.Pb.ValueDisplayFormat = '%.2f';
            app.Pb.Placeholder = 'Pb';
            app.Pb.Value = 2000;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Método'; 'Qo max (STB/d)'};
            app.UITable.RowName = {};
            app.UnidadButtonGroup = uibuttongroup(app.UIFigure);
            app.UnidadButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnidadButtonGroupSelectionChanged, true);
            app.UnidadButtonGroup.Title = 'Sistema de unidades';
            app.InglesButton = uitogglebutton(app.UnidadButtonGroup);
            app.InglesButton.Text = 'Inglés';
            app.InglesButton.Value = true;
            app.MexicoButton = uitogglebutton(app.UnidadButtonGroup);
            app.MexicoButton.Text = 'Métrico';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurvadeAfluencia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end